classdef App_automatische_Analyse_eines_Regelkreises_Kopie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        TabGroup_2                    matlab.ui.container.TabGroup
        ZeroPoleTab                   matlab.ui.container.Tab
        HTMLPZ                        matlab.ui.control.HTML
        PZButton                      matlab.ui.container.ButtonGroup
        SsButton                      matlab.ui.control.RadioButton
        TsButton                      matlab.ui.control.RadioButton
        KSsButton                     matlab.ui.control.RadioButton
        GSsButton                     matlab.ui.control.RadioButton
        LsButton                      matlab.ui.control.RadioButton
        KsButton                      matlab.ui.control.RadioButton
        GsButton                      matlab.ui.control.RadioButton
        pzplotUIAxes                  matlab.ui.control.UIAxes
        NyquistTab                    matlab.ui.container.Tab
        HTMLNyquistInfo               matlab.ui.control.HTML
        NyquistInfo                   matlab.ui.control.UIAxes
        nyquist_UIAxes2               matlab.ui.control.UIAxes
        BodeTab                       matlab.ui.container.Tab
        Bode_UIAxes                   matlab.ui.control.UIAxes
        BandwidthTab                  matlab.ui.container.Tab
        Bandwidth_UIAxes              matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ResponseTab                   matlab.ui.container.Tab
        HTMLkeyparamTable             matlab.ui.control.HTML
        ButtonGroup                   matlab.ui.container.ButtonGroup
        r_d12Button                   matlab.ui.control.RadioButton
        SetpointButton                matlab.ui.control.RadioButton
        StepButton                    matlab.ui.control.RadioButton
        Response_UIAxes               matlab.ui.control.UIAxes
        StationaryValuesTab           matlab.ui.container.Tab
        HTMLkeyparamTable_2           matlab.ui.control.HTML
        JumpLabel                     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SetpointandDisturbanceButton  matlab.ui.control.RadioButton
        OutputdisturbanceButton       matlab.ui.control.RadioButton
        SetButton                     matlab.ui.control.RadioButton
        Controllanderrorplo           matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        d_2_HTML                      matlab.ui.control.HTML
        d_1_HTML                      matlab.ui.control.HTML
        r_HTML                        matlab.ui.control.HTML
        K_HTML                        matlab.ui.control.HTML
        G_HTML                        matlab.ui.control.HTML
        HtmlTable                     matlab.ui.control.HTML
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        controlloopstabilityLabel     matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        Updated2Button                matlab.ui.control.Button
        Updated1Button                matlab.ui.control.Button
        UpdaterButton                 matlab.ui.control.Button
        UpdateKButton                 matlab.ui.control.Button
        UpdateGButton                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        r_UIAxes                      matlab.ui.control.UIAxes
        K_UIAxes                      matlab.ui.control.UIAxes
        G_UIAxes                      matlab.ui.control.UIAxes
        d_2_UIAxes                    matlab.ui.control.UIAxes
        d_1_UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        t
        r_num_sections
        r_start_time
        r_end_time
        d1_num_sections
        d2_num_sections
        d1_start_time
        d1_end_time
        d1_time_steps
        d1_time_vector
        d2_start_time
        d2_end_time
        d2_time_steps
        d2_time_vector
        timeDescription
        cansysjump
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
        G_poles
        G_zeroes
        K_poles
        K_zeroes
        L_poles
        L_zeroes
        T_poles
        T_zeroes
        S_poles
        S_zeroes
        GS_poles
        GS_zeroes
        KS_poles
        KS_zeroes
    end
    
    methods (Access = private)


        function displayTransferFunctionInApp(app, G, label)
    % DISPLAYTRANSFUNCTIONINAPP
    % ------------------------
    % This enhanced function displays the transfer function G as a LaTeX-formatted expression
    % in the UIAxes component. It uses improved formatting and error handling.
    %
    % Inputs:
    %   app   - The app object containing the UI components
    %   G     - The transfer function
    %   label - A string specifying the label (e.g., 'G', 'K', etc.)
    
    try
        % Extract numerator and denominator coefficients
        [num, den] = tfdata(G, 'v');
        
        % Remove leading zeros to get correct polynomial order
        num = removeLeadingZeros(num);
        den = removeLeadingZeros(den);
        
        % Convert the coefficients to LaTeX string representations
        num_str = poly2str(num, 's');
        den_str = poly2str(den, 's');
        
        % Replace scientific notation for LaTeX
        num_str = regexprep(num_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
        den_str = regexprep(den_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
        
        % Create the LaTeX fraction with improved formatting
        formulaLaTeX = ['$$' label '(s) = \frac{' num_str '}{' den_str '}$$'];
        
        % Determine the target UIAxes component (e.g., app.G_UIAxes)
        uiAxesName = [label '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Set axis properties for better display
        axis(uiAxesHandle, 'off');
        
        % Display the formula using the LaTeX interpreter with improved font size
        text(uiAxesHandle, 0.5, 0.5, formulaLaTeX, ...
            'Interpreter', 'latex', 'FontSize', 14, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    catch ME
        % Handle errors gracefully
        disp(['Error displaying transfer function: ' ME.message]);
        
        % Determine the target UIAxes component
        uiAxesName = [label '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(s)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end
end

function displayPlaceholder(app, label, variable)
    % DISPLAYPLACEHOLDER
    % ----------------
    % This function displays a placeholder formula when no actual function is defined.
    % The UI is improved with better text styling and centering.
    %
    % Inputs:
    %   app      - The app object containing the UI components
    %   label    - Function label (e.g., 'G', 'K', 'r', etc.)
    %   variable - Variable symbol (e.g., 's' or 't')

    % Create a visually appealing placeholder formula
    placeholderFormula = ['$$' label '(' variable ') = \textrm{not defined}$$'];
    
    % Get the target UIAxes handle
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    
    % Clear the axis and turn off axis lines
    cla(uiAxesHandle);
    axis(uiAxesHandle, 'off');
    
    % Display the placeholder formula with improved styling
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, ...
        'Interpreter', 'latex', 'FontSize', 14, ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'Color', [0.6 0.6 0.6]);  % Gray color to indicate placeholder status
end

function displayTimeFunction(app, target_value, num_sections, label, start_time, end_time)
    % DISPLAYTIMEFUNCTION
    % -----------------
    % Enhanced function to display time-domain functions with better formatting
    % and error handling. Supports continuous and piecewise functions.
    %
    % Inputs:
    %   app          - The app object containing UI components
    %   target_value - Function handle or cell array of function handles
    %   num_sections - Number of sections (1 for continuous)
    %   label        - Function label (e.g., 'r', 'd_1', etc.)
    %   start_time   - Vector of section start times
    %   end_time     - Vector of section end times
    
    try
        % Define symbolic variable t for LaTeX representation
        syms t;
        
        % Handle continuous vs. piecewise cases
        if num_sections == 1
            % Continuous case
            if isa(target_value, 'function_handle')
                % Try to convert function to symbolic for better display
                try
                    % Try to extract the function definition from string
                    funcStr = func2str(target_value);
                    exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                    
                    if ~isempty(exprStr) && ~isempty(exprStr{1})
                        % Convert to symbolic expression
                        timeFormula = str2sym(exprStr{1}{1});
                        latexTimeStr = latex(timeFormula);
                    else
                        % Fallback: simple string representation
                        latexTimeStr = 'f(t)';
                    end
                catch
                    % If conversion fails, use a generic representation
                    latexTimeStr = 'f(t)';
                end
            else
                % Constant value case
                latexTimeStr = num2str(target_value);
            end
            
            % Create LaTeX formula
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        else
            % Piecewise case: use \left\{ and array environment
            latexTimeStr = ['\left\{ \begin{array}{ll}'];
            
            % Add each piecewise segment
            for i = 1:num_sections
                if isa(target_value{i}, 'function_handle')
                    % Try to convert function to symbolic for better display
                    try
                        % Try to extract the function definition from string
                        funcStr = func2str(target_value{i});
                        exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                        
                        if ~isempty(exprStr) && ~isempty(exprStr{1})
                            % Convert to symbolic expression
                            timePart = str2sym(exprStr{1}{1});
                            latexTimePart = latex(timePart);
                        else
                            % Fallback: simple string representation
                            latexTimePart = 'f(t)';
                        end
                    catch
                        % If conversion fails, use a generic representation
                        latexTimePart = 'f(t)';
                    end
                else
                    % Constant value case (though this shouldn't happen in cell mode)
                    latexTimePart = num2str(target_value{i});
                end
                
                % Add time range condition and formatting
                latexTimeStr = [latexTimeStr, ' ', latexTimePart, ' & ', ...
                    num2str(start_time(i)), ' \leq t \leq ', num2str(end_time(i)), ' \\[5pt] '];
            end
            
            % Complete the piecewise function LaTeX
            latexTimeStr = [latexTimeStr, '\end{array}\right.'];
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        end
        
        % Get the target UIAxes component
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        axis(uiAxesHandle, 'off');
        
        % Display the formula with improved styling
        text(uiAxesHandle, 0.5, 0.5, combinedFormula, ...
            'Interpreter', 'latex', 'FontSize', 14, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    catch ME
        % Handle errors gracefully
        disp(['Error displaying time function: ' ME.message]);
        
        % Get the target UIAxes
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(t)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end
end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];


    % Display in the specified axes
    %text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end


function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
    % CONTROLLOOPSTABLE
    % ---------------
    % Enhanced stability check with more visual feedback
    
    try
        % Check stability
        if control_loop_stability(app.G, app.K) == true  
            message = 'The control loop is stable';
            color = [0.2 0.7 0.3]; % Green for stable
        else
            message = 'The control loop is unstable';
            color = [0.7 0.3 0.2]; % Red for unstable
        end
        
        % Update the label with improved styling
        app.controlloopstabilityLabel.Text = message;
        app.controlloopstabilityLabel.FontColor = color;
        app.controlloopstabilityLabel.FontWeight = 'bold';
        app.controlloopstabilityLabel.FontSize = 16;
    catch ME
        % Handle errors gracefully
        disp(['Error checking control loop stability: ' ME.message]);
        app.controlloopstabilityLabel.Text = 'Could not determine control loop stability';
        app.controlloopstabilityLabel.FontColor = [0.7 0.7 0.2]; % Yellow for warning
    end
end
        

function plot_TS_bandwidth(app, T, S)
    % Clear existing plots and disable legend
    cla(app.Bandwidth_UIAxes);
    legend(app.Bandwidth_UIAxes, 'off');
    
    % Calculate bandwidth of T
    try
        bandwidth_T = bandwidth(T);
        if isnan(bandwidth_T)
            disp('Warning: Standard bandwidth calculation failed, searching for -3dB bandwidth.');
            [mag, ~, freq] = bode(T);
            mag = squeeze(mag);
            bandwidth_idx = find(20*log10(mag) <= -3, 1, 'first');
            if isempty(bandwidth_idx)
                disp('No frequency crosses -3 dB. Using alternative approach.');
                % Alternative: search frequency range
                [mag, ~, freq] = bode(T, {0.01, 1000});
                mag = squeeze(mag);
                mag_db = 20*log10(mag);
                [peak_mag_db, peak_idx] = max(mag_db);
                cutoff_db = peak_mag_db - 3;
                bandwidth_idx = find(mag_db <= cutoff_db & freq > freq(peak_idx), 1, 'first');
                if ~isempty(bandwidth_idx)
                    bandwidth_T = freq(bandwidth_idx);
                else
                    bandwidth_T = NaN;
                    disp('Bandwidth could not be determined.');
                end
            else
                bandwidth_T = freq(bandwidth_idx);
            end
        end
    catch ME
        disp(['Error calculating bandwidth: ', ME.message]);
        bandwidth_T = NaN;
    end
    
    % Calculate frequency responses with better resolution
    try
        omega = logspace(-2, 2, 1000);
        
        [magT, ~, ~] = bode(T, omega);
        magT = squeeze(magT);
        magT_dB = 20 * log10(magT);
        
        [magS, ~, ~] = bode(S, omega);
        magS = squeeze(magS);
        magS_dB = 20 * log10(magS);
        
        % Plot T(s) (blue, solid)
        h1 = semilogx(app.Bandwidth_UIAxes, omega, magT_dB, 'b-', 'LineWidth', 2);
        hold(app.Bandwidth_UIAxes, 'on');
        
        % Plot S(s) (red, dashed)
        h2 = semilogx(app.Bandwidth_UIAxes, omega, magS_dB, 'r--', 'LineWidth', 1.5);
        
        % Add -3dB line (helpful for bandwidth determination)
        h3 = yline(app.Bandwidth_UIAxes, -3, 'k:', 'LineWidth', 1, 'Alpha', 0.7);
        
        % If bandwidth was calculated, mark it
        if ~isnan(bandwidth_T)
            % Get magnitude at bandwidth frequency
            [magAtBW, ~, ~] = bode(T, bandwidth_T);
            magAtBW_dB = 20 * log10(squeeze(magAtBW));
            
            % Draw vertical line at bandwidth frequency
            h4 = xline(app.Bandwidth_UIAxes, bandwidth_T, 'g-', 'LineWidth', 1.5);
            
            % Mark the bandwidth point on the T(s) curve
            h5 = plot(app.Bandwidth_UIAxes, bandwidth_T, magAtBW_dB, 'go', ...
                'MarkerSize', 10, 'MarkerFaceColor', 'g', 'LineWidth', 1.5);
            
            % Legend labels with bandwidth info
            legendLabels = {'T(s)', 'S(s)', '-3dB Line', ...
                ['Bandwidth: ', num2str(bandwidth_T, '%.3f'), ' rad/s'], ...
                ['Magnitude at BW: ', num2str(magAtBW_dB, '%.2f'), ' dB']};
            
            % Create legend
            legend(app.Bandwidth_UIAxes, [h1, h2, h3, h4, h5], legendLabels, 'Location', 'best');
        else
            % Legend without bandwidth info
            legend(app.Bandwidth_UIAxes, [h1, h2, h3], {'T(s)', 'S(s)', '-3dB Line'}, 'Location', 'best');
        end
        
        % Enhance the chart
        grid(app.Bandwidth_UIAxes, 'on');
        title(app.Bandwidth_UIAxes, 'Bandwidth Analysis');
        xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)');
        ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)');
        
        % Set chart limits for better visibility
        ylim(app.Bandwidth_UIAxes, [-40, 20]);
        
        hold(app.Bandwidth_UIAxes, 'off');
    catch ME
        disp(['Error plotting bandwidth: ', ME.message]);
        % Minimal error display in the chart
        text(app.Bandwidth_UIAxes, 0.5, 0.5, ['Error: ', ME.message], ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end

        
function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Clear the existing plots
    cla(app.Bode_UIAxes);
    
    % Plot the Bode diagram using bodeplot
    bode(app.Bode_UIAxes, L);
    
    % Add annotations within the axis for margins
    annotationStr = {
        ['Gain Margin: ', num2str(20*log10(Gm), '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(app.Bode_UIAxes, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 12, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

function render_html_table(app)
    % RENDER_HTML_TABLE
    % ---------------
    % Enhanced HTML table rendering with improved styling and better organization
    
    % Generate string representations of transfer functions
    G_table = createHtmlStringfortransfer(app.G, 'G'); 
    K_table = createHtmlStringfortransfer(app.K, 'K'); 
    L_table = createHtmlStringfortransfer(app.L, 'L'); 
    T_table = createHtmlStringfortransfer(app.T, 'T'); 
    S_table = createHtmlStringfortransfer(app.S, 'S'); 
    GS_table = createHtmlStringfortransfer(app.GS, 'GS'); 
    KS_table = createHtmlStringfortransfer(app.KS, 'KS'); 
    
    % Check conditions for each transfer function
    [G_stable, G_proper, G_sternly] = check_tf_conditions(app.G);
    [K_stable, K_proper, K_sternly] = check_tf_conditions(app.K);
    [L_stable, L_proper, L_sternly] = check_tf_conditions(app.L);
    [T_stable, T_proper, T_sternly] = check_tf_conditions(app.T);
    [S_stable, S_proper, S_sternly] = check_tf_conditions(app.S);
    [GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
    [KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);
    
    % Create an enhanced HTML table with improved styling
    htmlContent = sprintf(['<html>'...
        '<head>'...
        '<style>'...
        'table { border-collapse: collapse; width: 100%%; font-family: Arial, sans-serif; }'...
        'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }'...
        'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }'...
        'tr:nth-child(odd) { background-color: #F9F9F9; }'...
        'tr:hover { background-color: #E8F4F8; }'...
        '.check { color: #2ECC71; font-size: 18px; }'... % Green checkmark
        '.cross { color: #E74C3C; font-size: 18px; }'... % Red cross
        '.tf-cell { text-align: left; padding-left: 15px; }'...
        '</style>'...
        '</head>'...
        '<body>'...
        '<table>'...
        '<tr><th>Transfer Function</th><th>Stable</th><th>Proper</th><th>Strictly Proper</th></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '</table>'...
        '</body>'...
        '</html>'], ...
        G_table,  boolToSymbol(G_stable),  boolToSymbol(G_proper),  boolToSymbol(G_sternly), ...
        K_table,  boolToSymbol(K_stable),  boolToSymbol(K_proper),  boolToSymbol(K_sternly), ...
        L_table,  boolToSymbol(L_stable),  boolToSymbol(L_proper),  boolToSymbol(L_sternly), ...
        T_table,  boolToSymbol(T_stable),  boolToSymbol(T_proper),  boolToSymbol(T_sternly), ...
        S_table,  boolToSymbol(S_stable),  boolToSymbol(S_proper),  boolToSymbol(S_sternly), ...
        GS_table, boolToSymbol(GS_stable), boolToSymbol(GS_proper), boolToSymbol(GS_sternly), ...
        KS_table, boolToSymbol(KS_stable), boolToSymbol(KS_proper), boolToSymbol(KS_sternly));
    
    % Update the HTML component
    try
        app.HtmlTable.HTMLSource = htmlContent;
    catch
        % Create component if it doesn't exist
        app.HtmlTable = uihtml(app.Panel, 'HTMLSource', htmlContent);
        app.HtmlTable.Position = [10 10 app.Panel.Position(3)-20 app.Panel.Position(4)-20];
    end
end

        
function plotnyquist(app)
    % PLOTNYQUIST
    % ----------
    % Enhanced Nyquist diagram plotting function for control loop analysis
    
    try
        % Get the Nyquist diagram data
        [lReal, lImag, ~] = nyquistSiso(app.L);
        
        % Clear and prepare the axis
        cla(app.nyquist_UIAxes2);
        hold(app.nyquist_UIAxes2, 'on');
        
        % Plot the Nyquist diagram
        plot(app.nyquist_UIAxes2, lReal, lImag, 'b', 'LineWidth', 2);
        grid(app.nyquist_UIAxes2, 'on');
        
        % Mark special points
        plot(app.nyquist_UIAxes2, lReal(1), lImag(1), 'oc', 'LineWidth', 2);  % ω=0
        plot(app.nyquist_UIAxes2, lReal(end), lImag(end), 'xc', 'LineWidth', 2);  % ω=∞
        plot(app.nyquist_UIAxes2, -1, 0, '+r', 'LineWidth', 2);  % Critical point
        
        % Add labels and title
        xlabel(app.nyquist_UIAxes2, 'Real Axis');
        ylabel(app.nyquist_UIAxes2, 'Imaginary Axis');
        title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point');
        
        % Calculate poles, zeros, and delta phi
        [m_0, n_0, deltaphi] = countPolesforNyquist(app.L);
        
        % Check for unstable pole-zero cancellation
        if checkUnstablePoleZeroCancellation(app.G, app.K)
            pzcancel = 'with';
            pzColor = '#E74C3C'; % Red color for warning
        else
            pzcancel = 'without';
            pzColor = '#2ECC71'; % Green color for good
        end
        
        % Check if L(s) is proper
        if ~proper_tf(app.L)
            lprop = 'not';
            lpropColor = '#E74C3C'; % Red color for warning
        else
            lprop = '';
            lpropColor = '#2ECC71'; % Green color for good
        end
        
        % Create an HTML table with improved styling
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.warning { color: ', pzColor, '; font-weight: bold; }', ...
                '.proper { color: ', lpropColor, '; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="1">Information for Nyquist Analysis</th></tr>', ...
                    '<tr><td class="warning">G(s) and K(s) ', pzcancel, ' pole/zero cancellation</td></tr>', ...
                    '<tr><td class="proper">L(s) is ', lprop, ' proper</td></tr>', ...
                    '<tr><td>m = 180°; m_0 = ', num2str(m_0), '</td></tr>', ...
                    '<tr><td>n = 90°; n_0 = ', num2str(n_0), '</td></tr>', ...
                    '<tr><td>&Delta;&phi; = ', num2str(deltaphi), '°</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLNyquistInfo.HTMLSource = htmlTableStr;
        
        hold(app.nyquist_UIAxes2, 'off');
    catch ME
        % Handle errors gracefully
        disp(['Error plotting Nyquist diagram: ' ME.message]);
        
        % Display error message in plot
        cla(app.nyquist_UIAxes2);
        text(app.nyquist_UIAxes2, 0.5, 0.5, 'Error plotting Nyquist diagram', ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red');
        
        % Clear HTML table
        app.HTMLNyquistInfo.HTMLSource = '<html><body><p style="color:red;">Error generating Nyquist analysis.</p></body></html>';
    end
end


function calculateAndDisplayResponse(app, selectedButton)
    app.Response_UIAxes.Visible = 'on';
    
    % Standardtitel setzen
    responseTitle = 'System Response';
    
    % Prüfen, ob der Zeitvektor für Sollwert gesetzt ist
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            disp('Failed to create time vector');
            return;
        end
    end
    
    try
        % Eingabewerte für die Simulation vorbereiten
        [input_r, input_d1, input_d2] = prepareInputValues(app);
        
        % WICHTIG: ZUERST alle vorhandenen Plots löschen
        cla(app.Response_UIAxes);
        
        % Verschiedene Fälle basierend auf dem ausgewählten Button
        if selectedButton == app.StepButton
            % Sprungantwort
            try
                % Einheitssprung für den Zeitvektor erzeugen
                u = ones(size(app.t));
                
                % Systemantwort berechnen
                [y, t_out] = lsim(app.T, u, app.t);
                
                % EINFACH: Nur einen Plot erstellen mit direkter Legendenbezeichnung
                plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');
                
                % Titel setzen
                responseTitle = 'System Step Response';
                
                % Schlüsselparameter anzeigen
                displayKeyParameters(app, y, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        elseif selectedButton == app.SetpointButton
            % Antwort auf den benutzerdefinierten Sollwert
            try
                % Systemantwort berechnen
                [y, t_out] = lsim(app.T, input_r, app.t);
                
                % System Response plotten
                plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');
                hold(app.Response_UIAxes, 'on');
                
                % Sollwert plotten
                plot(app.Response_UIAxes, app.t, input_r, '--', 'DisplayName', 'Reference Input');
                hold(app.Response_UIAxes, 'off');
                
                % Titel setzen
                responseTitle = 'System Response to Setpoint r';
                
                % Schlüsselparameter anzeigen
                displayKeyParameters(app, y, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        elseif selectedButton == app.r_d12Button
            % Antwort auf Sollwert und Störgrößen
            try
                % Geschlossener Regelkreis definieren
                closed_loop_system = feedback(app.L, 1);
                
                % Antwort auf Sollwert berechnen
                [y_r, t_out] = lsim(closed_loop_system, input_r, app.t);
                
                % Übertragungsfunktionen für Störgrößen
                H_d1 = feedback(1, app.L); % Übertragungsfunktion für Störgröße D1
                H_d2 = feedback(app.G, app.K * app.G); % Übertragungsfunktion für Störgröße D2
                
                % Antworten auf Störgrößen berechnen
                [y_d1, ~] = lsim(H_d1, input_d1, app.t);
                [y_d2, ~] = lsim(H_d2, input_d2, app.t);
                
                % Gesamtantwort
                y_total = y_r + y_d1 + y_d2;
                
                % Regelfehler berechnen
                error = input_r - y_total;
                
                % Plotten mit minimaler Anzahl Plots und klaren Bezeichnungen
                plot(app.Response_UIAxes, t_out, y_total, 'DisplayName', 'System Response');
                hold(app.Response_UIAxes, 'on');
                
                % Input plotten
                plot(app.Response_UIAxes, app.t, input_r, '--', 'DisplayName', 'Reference Input');
                
                % Error plotten
                plot(app.Response_UIAxes, t_out, error, '-.', 'DisplayName', 'Control Error');
                
                hold(app.Response_UIAxes, 'off');
                
                % Titel setzen
                responseTitle = 'System Response to Setpoint r and Disturbances';
                
                % Schlüsselparameter anzeigen
                displayKeyParameters(app, y_total, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        end
    catch ME
        disp(['Error generating input values: ', ME.message]);
        return;
    end
    
    % Titel und Labels setzen
    title(app.Response_UIAxes, responseTitle);
    xlabel(app.Response_UIAxes, 'Time (s)');
    ylabel(app.Response_UIAxes, 'Amplitude');
    
    % Gitter aktivieren
    grid(app.Response_UIAxes, 'on');
    
    % Standardmäßig Legende anzeigen
    legend(app.Response_UIAxes, 'show');
end


function setTimeAndClose(app)

    app.t = create_linear_time_vector(app.r_num_sections); 

    % Generate LaTeX-compatible string
    latexStr = ['$\mbox{From ', num2str(startTime), ' to ', num2str(endTime), ' with ', num2str(stepSize), ' steps}$'];
    app.timeDescription = latexStr;
  
end
        
function displayKeyParameters(app, y, t_vector)
    % DISPLAYKEYPARAMETERS
    % ------------------
    % Enhanced function to display key response parameters with improved
    % formatting and error handling.
    
    try
        % Ensure y and t_vector have matching lengths
        if length(y) ~= length(t_vector)
            disp('Warning: Sizes of y and t_vector mismatch in displayKeyParameters');
            if length(y) > length(t_vector)
                y = y(1:length(t_vector));
            elseif length(t_vector) > length(y)
                t_vector = t_vector(1:length(y));
            end
        end
        
        % Generate system response information using stepinfo
        info = stepinfo(y, t_vector);
        
        % Extract data from the stepinfo structure
        RiseTime = info.RiseTime;
        SettlingTime = info.SettlingTime;
        SettlingMin = info.SettlingMin;
        SettlingMax = info.SettlingMax;
        Overshoot = info.Overshoot;
        Undershoot = info.Undershoot;
        Peak = info.Peak;
        PeakTime = info.PeakTime;
        
        % Check for TransientTime field
        if isfield(info, 'TransientTime')
            TransientTime = info.TransientTime;
        else
            TransientTime = NaN;
        end
        
        % Format values with proper precision
        RiseTime_str = formatNumber(RiseTime);
        SettlingTime_str = formatNumber(SettlingTime);
        SettlingMin_str = formatNumber(SettlingMin);
        SettlingMax_str = formatNumber(SettlingMax);
        Overshoot_str = [formatNumber(Overshoot), ' %']; % Add percentage symbol
        Undershoot_str = formatNumber(Undershoot);
        Peak_str = formatNumber(Peak);
        PeakTime_str = formatNumber(PeakTime);
        TransientTime_str = formatNumber(TransientTime);
        
        % Create enhanced HTML table with better styling
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { text-align: left; font-weight: bold; width: 60%; }', ...
                'td:last-child { text-align: right; width: 40%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="2">Key Parameters of a Response</th></tr>', ...
                    '<tr><td>Rise time t<sub>r</sub></td><td>', RiseTime_str, '</td></tr>', ...
                    '<tr><td>Settling time (2% band) t<sub>s</sub></td><td>', SettlingTime_str, '</td></tr>', ...
                    '<tr><td>Settling Minimum</td><td>', SettlingMin_str, '</td></tr>', ...
                    '<tr><td>Settling Maximum</td><td>', SettlingMax_str, '</td></tr>', ...
                    '<tr><td>Overshoot</td><td>', Overshoot_str, '</td></tr>', ...
                    '<tr><td>Undershoot</td><td>', Undershoot_str, '</td></tr>', ...
                    '<tr><td>Peak</td><td>', Peak_str, '</td></tr>', ...
                    '<tr><td>Peak Time</td><td>', PeakTime_str, '</td></tr>', ...
                    '<tr><td>Transient Time</td><td>', TransientTime_str, '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLkeyparamTable.HTMLSource = htmlTableStr;
    catch ME
        % Handle errors in a more user-friendly way
        disp(['Error in displayKeyParameters: ', ME.message]);
        
        % Create error table
        errorTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #E74C3C; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 12px; text-align: center; font-size: 14px; }', ...
                '.error { color: #E74C3C; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Response Analysis Error</th></tr>', ...
                    '<tr><td class="error">Could not calculate key parameters</td></tr>', ...
                    '<tr><td>Possible issues: non-standard response shape, insufficient data points, or negative time values</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        app.HTMLkeyparamTable.HTMLSource = errorTableStr;
    end
    
    % Helper function to format numbers with appropriate precision
    function str = formatNumber(num)
        if isnan(num)
            str = 'N/A';
        elseif abs(num) > 1000 || abs(num) < 0.01
            % Use scientific notation for very large/small numbers
            str = sprintf('%.4e', num);
        else
            % Use fixed-point notation with appropriate precision
            str = sprintf('%.4f', num);
        end
    end
end

        
function calculateAndDisplayErrorandOutput(app, selectedButton)
    % CALCULATEANDDISPLAYERRORANDOUTPUT - Berechnet und zeigt stationäre Werte an
    % Diese Funktion wurde überarbeitet, um die gleiche Eingabevorbereitung wie
    % calculateAndDisplayResponse zu verwenden und somit Dimensionsprobleme zu vermeiden.
    
    % Sichtbarkeit der Antwort-UIAxes aktivieren
    app.Controllanderrorplo.Visible = 'on';
    
    % Prüfen, ob gültige Übertragungsfunktionen vorhanden sind
    if ~isa(app.T, 'tf') || ~isa(app.S, 'tf') || ~isa(app.G, 'tf')
        uialert(app.UIFigure, 'Please define valid transfer functions G(s) and K(s) first.', 'Invalid System');
        return;
    end
    
    % Standardwerte für Sollwert und Störgrößen
    if isempty(app.r_s)
        app.r_s = 1;
    end
    
    if isempty(app.d_1_s)
        app.d_1_s = 0;
    end
    
    if isempty(app.d_2_s)
        app.d_2_s = 0;
    end
    
    disp(['Reference Input (r_s): ', num2str(app.r_s)]);
    disp(['Output Disturbance (d_1_s): ', num2str(app.d_1_s)]);
    disp(['Input Disturbance (d_2_s): ', num2str(app.d_2_s)]);
    
    % Zeitvektor prüfen
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            uialert(app.UIFigure, 'Unable to create time vector. Please set time parameters.', 'Error');
            return;
        end
    end
    
    % Eingabewerte vorbereiten mit der gleichen Funktion wie in calculateAndDisplayResponse
    try
        [input_r, input_d1, input_d2] = prepareInputValues(app);
    catch ME
        uialert(app.UIFigure, ['Error preparing inputs: ', ME.message], 'Error');
        disp(['Error details: ', getReport(ME)]);
        return;
    end
    
    % Struktur für die Simulation vorbereiten
    inputs_constant = struct('reference', true, 'disturbance1', true, 'disturbance2', true);
    
    % Prüfen, ob Eingaben konstant sind
    if iscell(app.r)
        inputs_constant.reference = false;
    end
    
    if iscell(app.d_1)
        inputs_constant.disturbance1 = false;
    end
    
    if iscell(app.d_2)
        inputs_constant.disturbance2 = false;
    end
    
    % Basierend auf dem ausgewählten Button unterschiedliche Simulationen durchführen
    if selectedButton == app.SetButton
        % Nur Sollwert: r konstant und Störgrößen = 0
        try
            % Simulation ausführen
            [y_TR, t_out] = lsim(app.T, input_r, app.t);
            [e_SR, ~] = lsim(app.S, input_r, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint = const and disturbances = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_TR, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SR, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_TR) && length(y_TR) > 10
                endpart = max(1, round(0.9*length(y_TR)));
                y_stat_TR = mean(y_TR(endpart:end));
                e_stat_SR = mean(e_SR(endpart:end));
            else
                y_stat_TR = y_TR(end);
                e_stat_SR = e_SR(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SR) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_TR, e_stat_SR, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.OutputdisturbanceButton
        % Nur Ausgangsstörung: r = 0, d_1 konstant
        try
            % Simulation ausführen
            [y_SD1, t_out] = lsim(-app.S, input_d1, app.t);
            [e_SD1, ~] = lsim(app.S, input_d1, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Output disturbance = const and r = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_SD1, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SD1, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_d1, ':', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_SD1) && length(y_SD1) > 10
                endpart = max(1, round(0.9*length(y_SD1)));
                y_stat_SD1 = mean(y_SD1(endpart:end));
                e_stat_SD1 = mean(e_SD1(endpart:end));
            else
                y_stat_SD1 = y_SD1(end);
                e_stat_SD1 = e_SD1(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SD1) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_SD1, e_stat_SD1, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.SetpointandDisturbanceButton
        % Kombinierter Fall: r und Ausgangsstörung
        try
            % Simulation für die kombinierte Antwort
            [y_r, t_out] = lsim(app.T, input_r, app.t);
            [y_d, ~] = lsim(-app.S, input_d1, app.t);
            y_total = y_r + y_d;  % Kombinierte Antwort
            
            [e_r, ~] = lsim(app.S, input_r, app.t);
            [e_d, ~] = lsim(app.S, input_d1, app.t);
            e_total = e_r + e_d;  % Kombinierter Fehler
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint and Output disturbance = const';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_total, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_total, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            plot(app.Controllanderrorplo, app.t, input_d1, '-.', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_total) && length(y_total) > 10
                endpart = max(1, round(0.9*length(y_total)));
                y_stat_combined = mean(y_total(endpart:end));
                e_stat_combined = mean(e_total(endpart:end));
            else
                y_stat_combined = y_total(end);
                e_stat_combined = e_total(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_combined) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_combined, e_stat_combined, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    end
    
    % Titel und Labels setzen
    title(app.Controllanderrorplo, responseTitle);
    xlabel(app.Controllanderrorplo, 'Time (s)');
    ylabel(app.Controllanderrorplo, 'Amplitude');
    grid(app.Controllanderrorplo, 'on');
    legend(app.Controllanderrorplo, 'Location', 'best');
end


function displayStationaryValues(app, y, e, inputs_constant)
    % DISPLAYSTATIONARYVALUES
    % ---------------------
    % Enhanced display of steady-state values with better formatting and more information
    
    % Define threshold for considering values as effectively zero
    threshold = 1e-6;
    
    % Add warnings for non-constant inputs
    warning_msg = '';
    if nargin >= 4 && isstruct(inputs_constant)
        if ~inputs_constant.reference
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Reference input is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance1
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Output disturbance is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance2
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Input disturbance is not constant.</td></tr>'];
        end
        
        if ~isempty(warning_msg)
            warning_msg = [warning_msg, '<tr><td class="warning">Steady-state values are based on simulation end points.</td></tr>'];
        end
    end
    
    % Format output value string with improved handling of special cases
    if isnan(y)
        disp('Warning: Output value is NaN, using 0 instead for display');
        y = 0;
        y_str = '<span class="estimate">y<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(y) < threshold
        y_str = 'y<sub>∞</sub> = 0';
    else
        % Format y with 4 significant digits and scientific notation for very large/small values
        if abs(y) > 10000 || abs(y) < 0.01
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4e', y)];
        else
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4g', y)];
        end
    end
    
    % Format error value string
    if isnan(e)
        disp('Warning: Error value is NaN, using 0 instead for display');
        e = 0;
        e_str = '<span class="estimate">e<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(e) < threshold
        e_str = 'e<sub>∞</sub> = 0';
    else
        % Format e with scientific notation if needed
        if abs(e) > 10000 || abs(e) < 0.01
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4e', e)];
        else
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4g', e)];
        end
    end
    
    % Set jumpability message with improved styling
    if app.cansysjump == 1
        jumpMessage = 'The control loop is jumpable (Zero steady-state error for step input)';
        jumpColor = '#2ECC71'; % Green color for positive result
    else
        jumpMessage = 'The control loop is not jumpable (Has steady-state error for step input)';
        jumpColor = '#E74C3C'; % Red color for negative result
    end
    app.JumpLabel.Text = jumpMessage;
    app.JumpLabel.FontColor = jumpColor;
    
    % Create enhanced HTML table
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
            'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
            'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }', ...
            'tr:nth-child(even) { background-color: #F2F2F2; }', ...
            'tr:hover { background-color: #E8F4F8; }', ...
            '.warning { color: #E74C3C; font-weight: bold; }', ...
            '.info { color: #3498DB; font-style: italic; }', ...
            '.estimate { color: #E67E22; font-style: italic; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th>Stationary Values</th></tr>', ...
                '<tr><td>', y_str, '</td></tr>', ...
                '<tr><td>', e_str, '</td></tr>', ...
                '<tr><td class="info">Based on simulation results</td></tr>', ...
                warning_msg, ...
            '</table>', ...
        '</body></html>'];
    
    % Update the HTML component
    app.HTMLkeyparamTable_2.HTMLSource = htmlTableStr;
    
    % Display additional diagnostic info in console
    disp(['Steady-state output: ', strrep(y_str, '<sub>∞</sub>', '(∞)')]);
    disp(['Steady-state error: ', strrep(e_str, '<sub>∞</sub>', '(∞)')]);
    disp(['System jumpability: ', app.JumpLabel.Text]);
end


function PZchange(app, selectedButton)
    % PZCHANGE
    % -------
    % Handles changes when a different transfer function is selected for pole-zero analysis
    
    try
        % Select the appropriate transfer function based on button selection
        if selectedButton == app.GsButton
            sys = app.G;
            varName = 'G';
        elseif selectedButton == app.TsButton
            sys = app.T;
            varName = 'T';
        elseif selectedButton == app.KsButton
            sys = app.K;
            varName = 'K';
        elseif selectedButton == app.KSsButton
            sys = app.KS;
            varName = 'KS';
        elseif selectedButton == app.GSsButton
            sys = app.GS;
            varName = 'GS';
        elseif selectedButton == app.LsButton
            sys = app.L;
            varName = 'L';
        elseif selectedButton == app.SsButton
            sys = app.S;
            varName = 'S';
        else
            error('Unknown transfer function button selected.');
        end
        
        % Debug output
        disp(['Selected Button: ', char(selectedButton.Tag)]);
        disp(['VarName: ', varName]);
        
        % Calculate poles and zeros
        poles = pole(sys);
        zeroes = zero(sys);
        
        % Create HTML strings for poles and zeros with improved formatting
        polesStr = formatPZList(poles, 'p');
        zeroesStr = formatPZList(zeroes, 'z');
        
        % Create enhanced HTML table
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td.pz-list { padding: 12px; text-align: left; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Poles and Zeros of ', varName, '(s)</th></tr>', ...
                    '<tr><td class="pz-list">', polesStr, '</td></tr>', ...
                    '<tr><td class="pz-list">', zeroesStr, '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLPZ.HTMLSource = htmlTableStr;
        
        % Store poles and zeros in app
        app.([varName, '_poles']) = poles;
        app.([varName, '_zeroes']) = zeroes;
        
        % Old version of the pole-zero plot
        cla(app.pzplotUIAxes);
        pzplot(app.pzplotUIAxes, sys);
        
    catch ME
        % Handle errors gracefully
        disp(['Error in PZchange: ' ME.message]);
        
        % Display error message in HTML
        app.HTMLPZ.HTMLSource = [...
            '<html><head><style>', ...
                'div.error { color: red; font-weight: bold; text-align: center; padding: 20px; }', ...
            '</style></head><body>', ...
                '<div class="error">Error displaying poles and zeros:<br>', ME.message, '</div>', ...
            '</body></html>'];
        
        % Display error in plot
        cla(app.pzplotUIAxes);
        text(app.pzplotUIAxes, 0.5, 0.5, 'Error displaying pole-zero plot', ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red');
    end
    
    % Helper function to format poles/zeros list
    function str = formatPZList(values, type)
        if isempty(values)
            if strcmp(type, 'p')
                str = 'No poles';
            else
                str = 'No zeros';
            end
            return;
        end
        
        str = '';
        for i = 1:length(values)
            % Format complex numbers nicely
            if imag(values(i)) == 0
                % Real value
                val_str = sprintf('%.4f', real(values(i)));
            elseif real(values(i)) == 0
                % Pure imaginary
                val_str = sprintf('%.4fi', imag(values(i)));
            elseif imag(values(i)) > 0
                % Complex with positive imaginary
                val_str = sprintf('%.4f + %.4fi', real(values(i)), imag(values(i)));
            else
                % Complex with negative imaginary
                val_str = sprintf('%.4f - %.4fi', real(values(i)), abs(imag(values(i))));
            end
            
            % Add to list with proper formatting
            if strcmp(type, 'p')
                str = [str, 'x<sub>p', num2str(i), '</sub> = ', val_str, '<br>'];
            else
                str = [str, 'x<sub>z', num2str(i), '</sub> = ', val_str, '<br>'];
            end
        end
    end
end


    function build_G(app)

    % Get the button text as the function label
    label = 'G';

    % Get the transfer function from the user
    app.G = get_user_transfer_function();
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.G)
        displayTransferFunctionInApp(app, app.G, label);
    end

                
    end


    function build_K(app)

    % Get the button text as the function label
    label = 'K';

    % Rufe User-Controller-Funktion auf und übergebe G direkt
    if ~isempty(app.G)
        app.K = get_user_controller(app.G);
    else
        % Wenn keine Regelstrecke definiert ist, Warnhinweis anzeigen
        app.K = get_user_controller(); % Ohne G aufrufen (Auto-Tuning wird deaktiviert sein)
    end
    
    % Wenn Benutzer nicht abgebrochen hat, zeige den Regler an
    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
    end
end


  function build_r(app)
    % Get the button text as the function label
    label = 'r';

    % Call the function without nested functions
    [app.r, app.r_num_sections, app.r_start_time, app.r_end_time] = get_target_value_version2();
    
    % In case r was defined already and is changed
    % the time vector changes as well
    % hence t needs to be defined new:
    app.t = create_linear_time_vector(app.r_num_sections);
    
    if ~isempty(app.r)
        displayTimeFunction(app, app.r, app.r_num_sections, label, app.r_start_time, app.r_end_time);
    end
  end


function saveAppVariables(app)
    % Liste der verfügbaren Variablen in der App
    variables = {
        'G', 'K', 'r', 'r_s', 'd_1', 'd_1_s', 'd_2', 'd_2_s', 'T', 't', 'r_num_sections', ...
        'd1_num_sections', 'd2_num_sections', 'd1_start_time', 'd1_end_time', 'd1_time_vector', ...
        'd2_start_time', 'd2_end_time', 'd2_time_vector', 'timeDescription', 'cansysjump', 'S', 'L', 'GS', 'KS', ...
        'char_eq', 'N_G', 'Z_G', 'N_K', 'Z_K', 'G_poles', 'G_zeroes', 'K_poles', 'K_zeroes', ...
        'L_poles', 'L_zeroes', 'T_poles', 'T_zeroes', 'S_poles', 'S_zeroes', 'GS_poles', 'GS_zeroes', ...
        'KS_poles', 'KS_zeroes'
    };

    % Initialize structure to save variables
    varsStruct = struct();

    % Assign values from app to the structure
    for i = 1:length(variables)
        varsStruct.(variables{i}) = app.(variables{i});
    end

    % Prompt user for filename
    filename = inputdlg('Enter filename (without extension):', 'Save Variables');
    if isempty(filename)
        msgbox('No filename entered. Operation cancelled.', 'Error', 'error');
        return;
    end
    filename = filename{1};
    
    % Remove invalid characters from filename
    invalidChars = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
    for i = 1:length(invalidChars)
        filename = strrep(filename, invalidChars{i}, '');
    end
    
    % Save the structure to a .mat file
    save([filename, '.mat'], '-struct', 'varsStruct');

    % Display confirmation message 
    msgbox('All done! Your variables are now saved!', 'Save Successful', 'help');
end

        
function success = loadAppVariables(app)
    % loadAppVariables lädt die benötigten Variablen aus einer .mat-Datei.
    % Falls keine Datei ausgewählt wird, wird die Funktion abgebrochen.
    %
    % \ac{Guard-Pattern} stellt sicher, dass nachfolgende Funktionen nur ausgeführt werden,
    % wenn eine Datei erfolgreich ausgewählt und geladen wurde \cite{DesignPatterns2020}.
    
    [file, path] = uigetfile('*.mat', 'Wählen Sie eine Datei aus');
    if isequal(file, 0)
        % Keine Datei ausgewählt – Abbruch der Funktion
        success = false;
        return;
    end
    
    % Erstellen des vollständigen Dateipfads und Laden der Daten
    fullFileName = fullfile(path, file);
    data = load(fullFileName);
    
    % Zuweisung der geladenen Variablen zu den App-Eigenschaften
    if isfield(data, 'G')
        app.G = data.G;
    else
        warning('Variable G wurde nicht gefunden.');
    end
    
    if isfield(data, 'K')
        app.K = data.K;
    else
        warning('Variable K wurde nicht gefunden.');
    end
    
    if isfield(data, 'r')
        app.r = data.r;
    else
        warning('Variable r wurde nicht gefunden.');
    end
    
    if isfield(data, 'r_num_sections')
        app.r_num_sections = data.r_num_sections;
    else
        warning('Variable r\_num\_sections wurde nicht gefunden.');
    end
    
    if isfield(data, 'd_1')
        app.d_1 = data.d_1;
    else
        warning('Variable d\_1 wurde nicht gefunden.');
    end
    
    if isfield(data, 'd1_num_sections')
        app.d1_num_sections = data.d1_num_sections;
    else
        warning('Variable d1\_num\_sections wurde nicht gefunden.');
    end
    
    if isfield(data, 'd_2')
        app.d_2 = data.d_2;
    else
        warning('Variable d\_2 wurde nicht gefunden.');
    end
    
    if isfield(data, 'd2_num_sections')
        app.d2_num_sections = data.d2_num_sections;
    else
        warning('Variable d2\_num\_sections wurde nicht gefunden.');
    end
    
    % Erfolgreiches Laden der Variablen – weitere Funktionen können ausgeführt werden
    success = true;
end


function [input_r, input_d1, input_d2] = prepareInputValues(app)
    % PREPAREINPUTVALUES - Bereitet die Eingabewerte für die Simulation vor
    % Diese Funktion wertet die Funktionen r, d_1 und d_2 für den gegebenen Zeitvektor aus,
    % unter Berücksichtigung, ob sie kontinuierlich oder stückweise definiert sind.
    
    % Initialisieren der Ausgabevektoren
    input_r = zeros(size(app.t));
    input_d1 = zeros(size(app.t));
    input_d2 = zeros(size(app.t));
    
    % Auswerten des Sollwerts
    if ~isempty(app.r)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.r)
            % Kontinuierlicher Fall
            if isa(app.r, 'function_handle')
                input_r = arrayfun(app.r, app.t);
            else
                % Konstanter Wert
                input_r = app.r * ones(size(app.t));
            end
        else
            % Stückweise Funktion
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Den aktiven Abschnitt für diesen Zeitpunkt finden
                active_section = 0;
                
                % Überprüfen, ob alle notwendigen Daten vorhanden sind
                if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && length(app.r) <= length(app.r_start_time)
                    for j = 1:length(app.r)
                        if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                            active_section = j;
                            break;
                        end
                    end
                end
                
                % Wenn kein aktiver Abschnitt gefunden wurde, den nächstgelegenen verwenden
                if active_section == 0 && ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
                    if current_time < app.r_start_time(1)
                        active_section = 1; % Zeit liegt vor dem ersten Abschnitt
                    elseif current_time > app.r_end_time(end)
                        active_section = length(app.r); % Zeit liegt nach dem letzten Abschnitt
                    end
                elseif active_section == 0 && length(app.r) > 0
                    active_section = 1; % Fallback: ersten Abschnitt verwenden
                end
                
                % Funktion auswerten, wenn ein aktiver Abschnitt gefunden wurde
                if active_section > 0 && active_section <= length(app.r)
                    if isa(app.r{active_section}, 'function_handle')
                        input_r(i) = app.r{active_section}(current_time);
                    else
                        input_r(i) = app.r{active_section};
                    end
                end
            end
        end
    end
    
    % Auswerten der ersten Störgröße
    if ~isempty(app.d_1)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_1)
            % Kontinuierlicher Fall
            if isa(app.d_1, 'function_handle')
                input_d1 = arrayfun(app.d_1, app.t);
            else
                % Konstanter Wert
                input_d1 = app.d_1 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei r
            % Da die Zeitbereiche für d_1 nicht explizit gespeichert werden, verwenden wir
            % die Zeitbereiche von r oder machen eine konservative Schätzung
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d1_num_sections > 0 && app.d1_num_sections <= length(app.d_1)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d1_num_sections <= length(app.r_start_time)
                        for j = 1:app.d1_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d1_num_sections;
                            for j = 1:app.d1_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_1)));
                
                % Funktion auswerten
                if isa(app.d_1{active_section}, 'function_handle')
                    input_d1(i) = app.d_1{active_section}(current_time);
                else
                    input_d1(i) = app.d_1{active_section};
                end
            end
        end
    end
    
    % Auswerten der zweiten Störgröße (gleicher Ansatz wie bei d_1)
    if ~isempty(app.d_2)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_2)
            % Kontinuierlicher Fall
            if isa(app.d_2, 'function_handle')
                input_d2 = arrayfun(app.d_2, app.t);
            else
                % Konstanter Wert
                input_d2 = app.d_2 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei d_1
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d2_num_sections > 0 && app.d2_num_sections <= length(app.d_2)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d2_num_sections <= length(app.r_start_time)
                        for j = 1:app.d2_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d2_num_sections;
                            for j = 1:app.d2_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_2)));
                
                % Funktion auswerten
                if isa(app.d_2{active_section}, 'function_handle')
                    input_d2(i) = app.d_2{active_section}(current_time);
                else
                    input_d2(i) = app.d_2{active_section};
                end
            end
        end
    end
end

    end %end of private methods

    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            displayPlaceholder(app, 'G', 's')
            displayPlaceholder(app, 'K', 's')
            displayPlaceholder(app, 'r', 't')
            displayPlaceholder(app, 'd_1', 't')
            displayPlaceholder(app, 'd_2', 't')
            
           
        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

        build_G(app);

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            build_K(app);
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            build_r(app);
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
    label = 'd_1';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_1, app.d1_num_sections, app.d1_start_time, app.d1_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && ~isempty(app.d1_end_time)
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, label);
        
        % Display the time function with the specific time parameters for d_1
        displayTimeFunction(app, app.d_1, app.d1_num_sections, label, app.d1_start_time, app.d1_end_time);
    end
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
    label = 'd_2';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_2, app.d2_num_sections, app.d2_start_time, app.d2_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && ~isempty(app.d2_end_time)
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, label);
        
        % Display the time function with the specific time parameters for d_2
        displayTimeFunction(app, app.d_2, app.d2_num_sections, label, app.d2_start_time, app.d2_end_time);
    end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            

            % when analyse button is pushsed
            % first check if all neccessary inputs are given
            % G, K, r(t) and t
            % if d1 and d2 are empty, they will be set to 0

        % Prüfen, ob G eingegeben wurde
    if isempty(app.G)
        build_G(app);
    end
    
    % Prüfen, ob K eingegeben wurde
    if isempty(app.K)
        build_K(app);
    end
    
    % Prüfen, ob r(t) eingegeben wurde
    if isempty(app.r)
        build_r(app);
    end
    
    % Zeitvektor erstellen, falls noch nicht geschehen
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
    else
        disp('Time vector already defined. Using cached values.');
    end
    
    % Störgrößenzeitvektoren erstellen
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && isempty(app.d1_time_vector)
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, 'd_1');
    end
    
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && isempty(app.d2_time_vector)
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, 'd_2');
    end


            %check if d1(t) and d2(t) were not entered 
            % if this is the case, set both to zero
                if isempty(app.d_1)
                app.d_1 = @(t) 0 + 0*t;
                app.d1_num_sections = 1;
                label = 'd_1';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end    

                if isempty(app.d_2)
                app.d_2 = @(t) 0 + 0*t;
                app.d2_num_sections = 1;
                label = 'd_2';
                displayTimeFunction(app, app.d_1, app.d1_num_sections, label)
                end


            [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

     
            render_html_table(app);
                
            app.PZButton.Visible = 'on';

            app.HtmlTable.Visible = 'on';

            app.HTMLPZ.Visible = 'on';

            app.SaveButton.Visible = 'on';

            app.ButtonGroup.Visible = 'on';

            app.ButtonGroup_2.Visible = 'on';

            app.nyquist_UIAxes2.Visible = "on";

            app.Bandwidth_UIAxes.Visible = "on";
            
            app.HTMLkeyparamTable_2.Visible = "on";

            app.HTMLNyquistInfo.Visible = "on";

            app.HTMLkeyparamTable.Visible = "on";

            plotnyquist(app);

            app.char_eq = get_characteristic_equation(app, app.G, app.K);

            controlloopstable(app);

            plot_TS_bandwidth(app, app.T, app.S);

            plot_bode_margins(app, app.L);

            calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);

            calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);

            PZchange(app, app.PZButton.SelectedObject);


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;

            calculateAndDisplayResponse(app, selectedButton);
        

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
    
            calculateAndDisplayErrorandOutput(app, selectedButton);

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveAppVariables(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if loadAppVariables(app)
                displayTransferFunctionInApp(app, app.G, 'G');
                displayTransferFunctionInApp(app, app.K, 'K');
                displayTimeFunction(app, app.r, app.r_num_sections, 'r');
                displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1');
                displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2');
            else
                % Optional: Benutzer informieren, dass keine Datei ausgewählt wurde.
                uialert(app.UIFigure, 'Keine Datei ausgewählt!', 'Hinweis');
                end
        end

        % Selection changed function: PZButton
        function PZButtonSelectionChanged(app, event)
            selectedButton = app.PZButton.SelectedObject;

            PZchange(app, selectedButton)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standardregelkreis.png');
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.controlloopstabilityLabel = uilabel(app.Panel);
            app.controlloopstabilityLabel.Text = '';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.HtmlTable = uihtml(app.Panel);
            app.HtmlTable.Visible = 'off';
            app.G_HTML = uihtml(app.Panel);
            app.G_HTML.Visible = 'off';
            app.K_HTML = uihtml(app.Panel);
            app.K_HTML.Visible = 'off';
            app.r_HTML = uihtml(app.Panel);
            app.r_HTML.Visible = 'off';
            app.d_1_HTML = uihtml(app.Panel);
            app.d_1_HTML.Visible = 'off';
            app.d_2_HTML = uihtml(app.Panel);
            app.d_2_HTML.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.Response_UIAxes = uiaxes(app.ResponseTab);
            title(app.Response_UIAxes, 'Title')
            xlabel(app.Response_UIAxes, 'X')
            ylabel(app.Response_UIAxes, 'Y')
            zlabel(app.Response_UIAxes, 'Z')
            app.Response_UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.ResponseTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.StepButton = uiradiobutton(app.ButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SetpointButton = uiradiobutton(app.ButtonGroup);
            app.SetpointButton.Text = 'Setpoint';
            app.r_d12Button = uiradiobutton(app.ButtonGroup);
            app.r_d12Button.Text = 'Setpoint and Disturbances';
            app.HTMLkeyparamTable = uihtml(app.ResponseTab);
            app.HTMLkeyparamTable.Visible = 'off';
            app.StationaryValuesTab = uitab(app.TabGroup);
            app.StationaryValuesTab.Title = 'Stationary Values';
            app.Controllanderrorplo = uiaxes(app.StationaryValuesTab);
            title(app.Controllanderrorplo, 'Title')
            xlabel(app.Controllanderrorplo, 'X')
            ylabel(app.Controllanderrorplo, 'Y')
            zlabel(app.Controllanderrorplo, 'Z')
            app.Controllanderrorplo.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.StationaryValuesTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.SetButton = uiradiobutton(app.ButtonGroup_2);
            app.SetButton.Text = 'Setpoint';
            app.SetButton.Value = true;
            app.OutputdisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.OutputdisturbanceButton.Text = 'Outputdisturbance';
            app.SetpointandDisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.SetpointandDisturbanceButton.Text = 'Setpoint and Outputdisturbance';
            app.JumpLabel = uilabel(app.StationaryValuesTab);
            app.JumpLabel.Text = '';
            app.HTMLkeyparamTable_2 = uihtml(app.StationaryValuesTab);
            app.HTMLkeyparamTable_2.Visible = 'off';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.ZeroPoleTab = uitab(app.TabGroup_2);
            app.ZeroPoleTab.Title = 'Zero/Pole';
            app.pzplotUIAxes = uiaxes(app.ZeroPoleTab);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.PZButton = uibuttongroup(app.ZeroPoleTab);
            app.PZButton.SelectionChangedFcn = createCallbackFcn(app, @PZButtonSelectionChanged, true);
            app.PZButton.Visible = 'off';
            app.GsButton = uiradiobutton(app.PZButton);
            app.GsButton.Text = 'G(s)';
            app.GsButton.Value = true;
            app.KsButton = uiradiobutton(app.PZButton);
            app.KsButton.Text = 'K(s)';
            app.LsButton = uiradiobutton(app.PZButton);
            app.LsButton.Text = 'L(s)';
            app.GSsButton = uiradiobutton(app.PZButton);
            app.GSsButton.Text = 'GS(s)';
            app.KSsButton = uiradiobutton(app.PZButton);
            app.KSsButton.Text = 'KS(s)';
            app.TsButton = uiradiobutton(app.PZButton);
            app.TsButton.Text = 'T(s)';
            app.SsButton = uiradiobutton(app.PZButton);
            app.SsButton.Text = 'S(s)';
            app.HTMLPZ = uihtml(app.ZeroPoleTab);
            app.HTMLPZ.Visible = 'off';
            app.NyquistTab = uitab(app.TabGroup_2);
            app.NyquistTab.Title = 'Nyquist';
            app.nyquist_UIAxes2 = uiaxes(app.NyquistTab);
            title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point')
            xlabel(app.nyquist_UIAxes2, 'Real Axis')
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.NyquistInfo = uiaxes(app.NyquistTab);
            xlabel(app.NyquistInfo, 'X')
            ylabel(app.NyquistInfo, 'Y')
            zlabel(app.NyquistInfo, 'Z')
            app.NyquistInfo.Visible = 'off';
            app.HTMLNyquistInfo = uihtml(app.NyquistTab);
            app.HTMLNyquistInfo.Visible = 'off';
            app.BodeTab = uitab(app.TabGroup_2);
            app.BodeTab.Title = 'Bode';
            app.Bode_UIAxes = uiaxes(app.BodeTab);
            title(app.Bode_UIAxes, 'Title')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.BandwidthTab = uitab(app.TabGroup_2);
            app.BandwidthTab.Title = 'Bandwidth';
            app.Bandwidth_UIAxes = uiaxes(app.BandwidthTab);
            title(app.Bandwidth_UIAxes, 'Magnitude Response with Bandwidth')
            xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)')
            ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises_Kopie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end